classdef GillermooKerbalEngineMapper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EnginePlotterTab                matlab.ui.container.Tab
        RunButton                       matlab.ui.control.Button
        PlotProgress                    matlab.ui.control.EditField
        RequiredTWREditField            matlab.ui.control.NumericEditField
        RequiredTWREditFieldLabel       matlab.ui.control.Label
        MaxEnginesEditField             matlab.ui.control.NumericEditField
        MaxEnginesEditFieldLabel        matlab.ui.control.Label
        PlotFinenessEditField           matlab.ui.control.NumericEditField
        PlotFinenessEditFieldLabel      matlab.ui.control.Label
        MaxPayloadEditField             matlab.ui.control.NumericEditField
        MaxPayloadEditFieldLabel        matlab.ui.control.Label
        MinPayloadEditField             matlab.ui.control.NumericEditField
        MinPayloadEditFieldLabel        matlab.ui.control.Label
        MaxDeltavEditField              matlab.ui.control.NumericEditField
        MaxDeltavEditFieldLabel         matlab.ui.control.Label
        MinDeltavEditField              matlab.ui.control.NumericEditField
        MinDeltavEditFieldLabel         matlab.ui.control.Label
        FlightConditionDropDown         matlab.ui.control.DropDown
        FlightConditionDropDownLabel    matlab.ui.control.Label
        AllowableEnginesTab             matlab.ui.container.Tab
        SelectAllButton                 matlab.ui.control.Button
        DeselectAllButton               matlab.ui.control.Button
        BobcatCheckBox                  matlab.ui.control.CheckBox
        PulloxCheckBox                  matlab.ui.control.CheckBox
        ClydesdaleCheckBox              matlab.ui.control.CheckBox
        ThoroughbredCheckBox            matlab.ui.control.CheckBox
        MiteCheckBox                    matlab.ui.control.CheckBox
        ShrimpCheckBox                  matlab.ui.control.CheckBox
        SepratronICheckBox              matlab.ui.control.CheckBox
        KickbackCheckBox                matlab.ui.control.CheckBox
        ThumperCheckBox                 matlab.ui.control.CheckBox
        HammerCheckBox                  matlab.ui.control.CheckBox
        FleaCheckBox                    matlab.ui.control.CheckBox
        CubCheckBox                     matlab.ui.control.CheckBox
        KodiakCheckBox                  matlab.ui.control.CheckBox
        WolfhoundCheckBox               matlab.ui.control.CheckBox
        SkiffCheckBox                   matlab.ui.control.CheckBox
        CheetahCheckBox                 matlab.ui.control.CheckBox
        MastodonCheckBox                matlab.ui.control.CheckBox
        DawnCheckBox                    matlab.ui.control.CheckBox
        RAPIERCheckBox                  matlab.ui.control.CheckBox
        MammothCheckBox                 matlab.ui.control.CheckBox
        RhinoCheckBox                   matlab.ui.control.CheckBox
        TwinBoarCheckBox                matlab.ui.control.CheckBox
        MainsailCheckBox                matlab.ui.control.CheckBox
        SkipperCheckBox                 matlab.ui.control.CheckBox
        PoodleCheckBox                  matlab.ui.control.CheckBox
        NervCheckBox                    matlab.ui.control.CheckBox
        DartCheckBox                    matlab.ui.control.CheckBox
        VectorCheckBox                  matlab.ui.control.CheckBox
        SwivelCheckBox                  matlab.ui.control.CheckBox
        ReliantCheckBox                 matlab.ui.control.CheckBox
        TerrierCheckBox                 matlab.ui.control.CheckBox
        SparkCheckBox                   matlab.ui.control.CheckBox
        AntCheckBox                     matlab.ui.control.CheckBox
        ThudCheckBox                    matlab.ui.control.CheckBox
        TwitchCheckBox                  matlab.ui.control.CheckBox
        SpiderCheckBox                  matlab.ui.control.CheckBox
        RocketPlannerTab                matlab.ui.container.Tab
        WhichOptimalDropDown            matlab.ui.control.DropDown
        WhichOptimalDropDownLabel       matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        RunOptimizerButton              matlab.ui.control.Button
        RocketOutputTextArea            matlab.ui.control.TextArea
        RocketOutputTextAreaLabel       matlab.ui.control.Label
        RocketInputTextArea             matlab.ui.control.TextArea
        RocketInputTextAreaLabel        matlab.ui.control.Label
        PutengineonAsparaguscoreCheckBox  matlab.ui.control.CheckBox
        MaximumSymmetryEditField        matlab.ui.control.NumericEditField
        MaximumSymmetryEditFieldLabel   matlab.ui.control.Label
        DeltavEditField                 matlab.ui.control.NumericEditField
        DeltavEditFieldLabel            matlab.ui.control.Label
        FlightConditionDropDown_2       matlab.ui.control.DropDown
        FlightConditionDropDown_2Label  matlab.ui.control.Label
        MaximumEnginesEditField         matlab.ui.control.NumericEditField
        MaximumEnginesEditFieldLabel    matlab.ui.control.Label
        StageTypeDropDown               matlab.ui.control.DropDown
        StageTypeDropDownLabel          matlab.ui.control.Label
        RemoveStageButton               matlab.ui.control.Button
        AddStageButton                  matlab.ui.control.Button
        PayloadtonsEditField            matlab.ui.control.NumericEditField
        PayloadtonsEditFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('EnginePreset.mat');
            
            app.SpiderCheckBox.Value = Engines{1,2};
            app.TwitchCheckBox.Value = Engines{2,2};
            app.ThudCheckBox.Value = Engines{3,2};
            app.AntCheckBox.Value = Engines{4,2};
            app.SparkCheckBox.Value = Engines{5,2};
            app.TerrierCheckBox.Value = Engines{6,2};
            app.ReliantCheckBox.Value = Engines{7,2};
            app.SwivelCheckBox.Value = Engines{8,2};
            app.VectorCheckBox.Value = Engines{9,2};
            app.DartCheckBox.Value = Engines{10,2};
            app.NervCheckBox.Value = Engines{11,2};
            app.PoodleCheckBox.Value = Engines{12,2};
            app.SkipperCheckBox.Value = Engines{13,2};
            app.MainsailCheckBox.Value = Engines{14,2};
            app.TwinBoarCheckBox.Value = Engines{15,2};
            app.RhinoCheckBox.Value = Engines{16,2};
            app.MammothCheckBox.Value = Engines{17,2};
            app.RAPIERCheckBox.Value = Engines{18,2};
            app.DawnCheckBox.Value = Engines{19,2};
            app.MastodonCheckBox.Value = Engines{20,2};
            app.CheetahCheckBox.Value = Engines{21,2};
            app.BobcatCheckBox.Value = Engines{22,2};
            app.SkiffCheckBox.Value = Engines{23,2};
            app.WolfhoundCheckBox.Value = Engines{24,2};
            app.KodiakCheckBox.Value = Engines{25,2};
            app.CubCheckBox.Value = Engines{26,2};
            app.FleaCheckBox.Value = Engines{27,2};
            app.HammerCheckBox.Value = Engines{28,2};
            app.ThumperCheckBox.Value = Engines{29,2};
            app.KickbackCheckBox.Value = Engines{30,2};
            app.SepratronICheckBox.Value = Engines{31,2};
            app.ShrimpCheckBox.Value = Engines{32,2};
            app.MiteCheckBox.Value = Engines{33,2};
            app.ThoroughbredCheckBox.Value = Engines{34,2};
            app.ClydesdaleCheckBox.Value = Engines{35,2};
            app.PulloxCheckBox.Value = Engines{36,2};
        end

        % Value changed function: AntCheckBox, BobcatCheckBox, 
        % ...and 34 other components
        function SaveAllowEngines(app, event)
            Engines = {
            'Spider'        ,app.SpiderCheckBox.Value;
            'Twitch'        ,app.TwitchCheckBox.Value;
            'Thud'          ,app.ThudCheckBox.Value;
            'Ant'           ,app.AntCheckBox.Value;
            'Spark'         ,app.SparkCheckBox.Value;
            'Terrier'       ,app.TerrierCheckBox.Value;
            'Reliant'       ,app.ReliantCheckBox.Value;
            'Swivel'        ,app.SwivelCheckBox.Value;
            'Vector'        ,app.VectorCheckBox.Value;
            'Dart'          ,app.DartCheckBox.Value;
            'Nerv'          ,app.NervCheckBox.Value;
            'Poodle'        ,app.PoodleCheckBox.Value;
            'Skipper'       ,app.SkipperCheckBox.Value;
            'Mainsail'      ,app.MainsailCheckBox.Value;
            'Twin-Boar'     ,app.TwinBoarCheckBox.Value;
            'Rhino'         ,app.RhinoCheckBox.Value;
            'Mammoth'       ,app.MammothCheckBox.Value;
            'R.A.P.I.E.R.'  ,app.RAPIERCheckBox.Value;
            'Dawn'          ,app.DawnCheckBox.Value;
            'Mastodon'      ,app.MastodonCheckBox.Value;
            'Cheetah'       ,app.CheetahCheckBox.Value;
            'Bobcat'        ,app.BobcatCheckBox.Value;
            'Skiff'         ,app.SkiffCheckBox.Value;
            'Wolfhound'     ,app.WolfhoundCheckBox.Value;
            'Kodiak'        ,app.KodiakCheckBox.Value;
            'Cub'           ,app.CubCheckBox.Value;
            'Flea'          ,app.FleaCheckBox.Value;
            'Hammer'        ,app.HammerCheckBox.Value;
            'Thumper'       ,app.ThumperCheckBox.Value;
            'Kickback'      ,app.KickbackCheckBox.Value;
            'Sepratron I'   ,app.SepratronICheckBox.Value;
            'Shrimp'        ,app.ShrimpCheckBox.Value;
            'Mite'          ,app.MiteCheckBox.Value;
            'Thoroughbred'  ,app.ThoroughbredCheckBox.Value;
            'Clydesdale'    ,app.ClydesdaleCheckBox.Value;
            'Pullox'        ,app.PulloxCheckBox.Value};
            save('EnginePreset.mat','Engines');
            
        end

        % Button pushed function: RunButton
        function RunButtonValueChanged(app, event)
            load('EnginePreset.mat');
        
            m = logspace(log10(app.MinPayloadEditField.Value),log10(app.MaxPayloadEditField.Value),app.PlotFinenessEditField.Value);
            dv = linspace(app.MinDeltavEditField.Value,app.MaxDeltavEditField.Value,app.PlotFinenessEditField.Value);
            
            [DV,M] = meshgrid(dv,m);
            
            info.t = 'lin';
            info.e = app.MaxEnginesEditField.Value;
            info.twr = app.RequiredTWREditField.Value;
            info.cond = app.FlightConditionDropDown.Value;
            if(strcmp('Sea Level',info.cond))
                info.cond = 'asl';
            else
                info.cond = 'v';
            end
            info.dv = DV;
            
            load('LFTanks.mat');
            load('RFTanks.mat');
            load('XenonTanks.mat');
            tanks.LF = LFTanks;
            tanks.RF = RFTanks;
            tanks.Xenon = XenonTanks;
            
            allowEngines = Engine.setupEngines(Engines);
            try
                h = RocketStage.plotLinearStage(info,M,allowEngines,tanks);
                
            catch
                app.PlotProgress.Value = "ERROR";
            end
            
        end

        % Value changed function: StageTypeDropDown
        function StageTypeDropDownValueChanged(app, event)
            value = app.StageTypeDropDown.Value;
            if(strcmp('Linear',value))
                app.MaximumSymmetryEditField.Enable = 'off';
                app.MaximumSymmetryEditFieldLabel.Enable = 'off';
                app.PutengineonAsparaguscoreCheckBox.Enable = 'off';
                app.PutengineonAsparaguscoreCheckBox.Enable = 'off';
                app.MaximumEnginesEditField.Enable = 'on';
                app.MaximumEnginesEditFieldLabel.Enable = 'on';
                app.FlightConditionDropDown_2.Enable = 'on';
                app.FlightConditionDropDown_2Label.Enable = 'on';
                app.DeltavEditField.Enable = 'on';
                app.DeltavEditFieldLabel.Enable = 'on';
                app.PayloadtonsEditField.Enable = 'off';
                app.PayloadtonsEditFieldLabel.Enable = 'off';
            elseif(strcmp('Asparagus',value))
                app.MaximumSymmetryEditField.Enable = 'on';
                app.MaximumSymmetryEditFieldLabel.Enable = 'on';
                app.PutengineonAsparaguscoreCheckBox.Enable = 'on';
                app.MaximumEnginesEditField.Enable = 'on';
                app.MaximumEnginesEditFieldLabel.Enable = 'on';
                app.FlightConditionDropDown_2.Enable = 'on';
                app.FlightConditionDropDown_2Label.Enable = 'on';
                app.DeltavEditField.Enable = 'on';
                app.DeltavEditFieldLabel.Enable = 'on';
                app.PayloadtonsEditField.Enable = 'off';
                app.PayloadtonsEditFieldLabel.Enable = 'off';
            else
                app.MaximumSymmetryEditField.Enable = 'off';
                app.MaximumSymmetryEditFieldLabel.Enable = 'off';
                app.PutengineonAsparaguscoreCheckBox.Enable = 'off';
                app.MaximumEnginesEditField.Enable = 'off';
                app.MaximumEnginesEditFieldLabel.Enable = 'of';
                app.FlightConditionDropDown_2.Enable = 'off';
                app.FlightConditionDropDown_2Label.Enable = 'off';
                app.DeltavEditField.Enable = 'off';
                app.DeltavEditFieldLabel.Enable = 'off';
                app.PayloadtonsEditField.Enable = 'on';
                app.PayloadtonsEditFieldLabel.Enable = 'on';
                
            end
        end

        % Button pushed function: AddStageButton
        function AddStageButtonPushed(app, event)
            value = app.StageTypeDropDown.Value;
            if(strcmp('Linear',value))
                if(strcmp('Sea Level',app.FlightConditionDropDown_2.Value))
                    cond = 'asl';
                else
                    cond = 'v';
                end
                str = string(['t:lin e:',char(num2str(app.MaximumEnginesEditField.Value)),' twr:',...
                    char(num2str(app.RequiredTWREditField.Value)),' cond:',cond,' dv:',char(num2str(app.DeltavEditField.Value))]);
                newval = {app.RocketInputTextArea.Value{:},str};
                if(newval{1} == "")
                    newval(1) = [];
                end
                app.RocketInputTextArea.Value = newval;
            elseif(strcmp('Asparagus',value))
                if(strcmp('Sea Level',app.FlightConditionDropDown_2.Value))
                    cond = 'asl';
                else
                    cond = 'v';
                end
                str = string(['t:asp e:',char(num2str(app.MaximumEnginesEditField.Value)),' twr:',...
                    char(num2str(app.RequiredTWREditField.Value)),' cond:',cond,' dv:',char(num2str(app.DeltavEditField.Value)),...
                    ' s:',char(num2str(app.MaximumSymmetryEditField.Value)),' center:', char(num2str(app.PutengineonAsparaguscoreCheckBox.Value))]);
                newval = {app.RocketInputTextArea.Value{:},str};
                if(newval{1} == "")
                    newval(1) = [];
                end
                app.RocketInputTextArea.Value = newval;
            else
                str = string(['t:pl pl:',char(num2str(app.PayloadtonsEditField.Value))]);
                newval = {app.RocketInputTextArea.Value{:},str};
                if(newval{1} == "")
                    newval(1) = [];
                end
                app.RocketInputTextArea.Value = newval;
            end
        end

        % Button pushed function: RemoveStageButton
        function RemoveStageButtonPushed(app, event)
            newval = {app.RocketInputTextArea.Value{:}};
            newval(end) = [];
            if(isempty(newval))
                newval = "";
            end
            app.RocketInputTextArea.Value = newval;
        end

        % Button pushed function: RunOptimizerButton
        function RunOptimizerButtonValueChanged(app, event)
            app.RocketOutputTextArea.Value ="RUNNING...";
            load('EnginePreset.mat');
        
            reqs = app.RocketInputTextArea.Value;
            load('LFTanks.mat');
            load('RFTanks.mat');
            load('XenonTanks.mat');
            tanks.LF = LFTanks;
            tanks.RF = RFTanks;
            tanks.Xenon = XenonTanks;
            
            allowEngines = Engine.setupEngines(Engines);
            
            try
              r1 = Rocket(reqs,allowEngines,tanks);
              if(strcmp(app.WhichOptimalDropDown.Value, 'Lowest Cost'))
                  vals = {r1.cost};
              else
                  vals = {r1.mass};
              end
              [val,idx] = min([vals{:}]);
              r0 = r1(idx);
              save('temp.mat','r1');
              
              [tbl,data,names,rows] = r0.toString();
              app.UITable.Data = data;
              app.UITable.RowName = rows;
              app.UITable.ColumnName = names;
              app.RocketOutputTextArea.Value ="DONE";
            catch 
              app.RocketOutputTextArea.Value = "ERROR";
            end
                        
            
        end

        % Value changed function: WhichOptimalDropDown
        function WhichOptimalDropDownValueChanged(app, event)
            value = app.WhichOptimalDropDown.Value;
            load('temp.mat');
            if(strcmp(app.WhichOptimalDropDown.Value, 'Lowest Cost'))
                  vals = {r1.cost};
              else
                  vals = {r1.mass};
              end
              [val,idx] = min([vals{:}]);
              r0 = r1(idx);
              
              [tbl,data,names,rows] = r0.toString();
              app.UITable.Data = data;
              app.UITable.RowName = rows;
              app.UITable.ColumnName = names;
              app.RocketOutputTextArea.Value ="DONE";
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            app.SpiderCheckBox.Value = false;
            app.TwitchCheckBox.Value = false;
            app.ThudCheckBox.Value = false;
            app.AntCheckBox.Value = false;
            app.SparkCheckBox.Value = false;
            app.TerrierCheckBox.Value = false;
            app.ReliantCheckBox.Value = false;
            app.SwivelCheckBox.Value = false;
            app.VectorCheckBox.Value = false;
            app.DartCheckBox.Value = false;
            app.NervCheckBox.Value = false;
            app.PoodleCheckBox.Value = false;
            app.SkipperCheckBox.Value = false;
            app.MainsailCheckBox.Value = false;
            app.TwinBoarCheckBox.Value = false;
            app.RhinoCheckBox.Value = false;
            app.MammothCheckBox.Value = false;
            app.RAPIERCheckBox.Value = false;
            app.DawnCheckBox.Value = false;
            app.MastodonCheckBox.Value = false;
            app.CheetahCheckBox.Value = false;
            app.BobcatCheckBox.Value = false;
            app.SkiffCheckBox.Value = false;
            app.WolfhoundCheckBox.Value = false;
            app.KodiakCheckBox.Value = false;
            app.CubCheckBox.Value = false;
            app.FleaCheckBox.Value = false;
            app.HammerCheckBox.Value = false;
            app.ThumperCheckBox.Value = false;
            app.KickbackCheckBox.Value = false;
            app.SepratronICheckBox.Value = false;
            app.ShrimpCheckBox.Value = false;
            app.MiteCheckBox.Value = false;
            app.ThoroughbredCheckBox.Value = false;
            app.ClydesdaleCheckBox.Value = false;
            app.PulloxCheckBox.Value = false;
            SaveAllowEngines(app, event);
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.SpiderCheckBox.Value = true;
            app.TwitchCheckBox.Value = true;
            app.ThudCheckBox.Value = true;
            app.AntCheckBox.Value = true;
            app.SparkCheckBox.Value = true;
            app.TerrierCheckBox.Value = true;
            app.ReliantCheckBox.Value = true;
            app.SwivelCheckBox.Value = true;
            app.VectorCheckBox.Value = true;
            app.DartCheckBox.Value = true;
            app.NervCheckBox.Value = true;
            app.PoodleCheckBox.Value = true;
            app.SkipperCheckBox.Value = true;
            app.MainsailCheckBox.Value = true;
            app.TwinBoarCheckBox.Value = true;
            app.RhinoCheckBox.Value = true;
            app.MammothCheckBox.Value = true;
            app.RAPIERCheckBox.Value = true;
            app.DawnCheckBox.Value = true;
            app.MastodonCheckBox.Value = true;
            app.CheetahCheckBox.Value = true;
            app.BobcatCheckBox.Value = true;
            app.SkiffCheckBox.Value = true;
            app.WolfhoundCheckBox.Value = true;
            app.KodiakCheckBox.Value = true;
            app.CubCheckBox.Value = true;
            app.FleaCheckBox.Value = true;
            app.HammerCheckBox.Value = true;
            app.ThumperCheckBox.Value = true;
            app.KickbackCheckBox.Value = true;
            app.SepratronICheckBox.Value = true;
            app.ShrimpCheckBox.Value = true;
            app.MiteCheckBox.Value = true;
            app.ThoroughbredCheckBox.Value = true;
            app.ClydesdaleCheckBox.Value = true;
            app.PulloxCheckBox.Value = true;
            SaveAllowEngines(app, event);
        end

        % Callback function
        function SpiderCheckBoxValueChanged2(app, event)
            value = app.SpiderCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EnginePlotterTab = uitab(app.TabGroup);
            app.EnginePlotterTab.Title = 'Engine Plotter';
            app.FlightConditionDropDownLabel = uilabel(app.EnginePlotterTab);
            app.FlightConditionDropDownLabel.Text = 'Flight Condition';
            app.FlightConditionDropDown = uidropdown(app.EnginePlotterTab);
            app.FlightConditionDropDown.Items = {'Sea Level', 'Vacuum'};
            app.FlightConditionDropDown.Value = 'Sea Level';
            app.MinDeltavEditFieldLabel = uilabel(app.EnginePlotterTab);
            app.MinDeltavEditFieldLabel.Text = 'Min Delta-v';
            app.MinDeltavEditField = uieditfield(app.EnginePlotterTab, 'numeric');
            app.MinDeltavEditField.Limits = [0 Inf];
            app.MinDeltavEditField.Value = 100;
            app.MaxDeltavEditFieldLabel = uilabel(app.EnginePlotterTab);
            app.MaxDeltavEditFieldLabel.Text = 'Max Delta-v';
            app.MaxDeltavEditField = uieditfield(app.EnginePlotterTab, 'numeric');
            app.MaxDeltavEditField.Limits = [0 Inf];
            app.MaxDeltavEditField.Value = 6000;
            app.MinPayloadEditFieldLabel = uilabel(app.EnginePlotterTab);
            app.MinPayloadEditFieldLabel.Text = 'Min Payload';
            app.MinPayloadEditField = uieditfield(app.EnginePlotterTab, 'numeric');
            app.MinPayloadEditField.LowerLimitInclusive = 'off';
            app.MinPayloadEditField.Limits = [0 Inf];
            app.MinPayloadEditField.Value = 0.01;
            app.MaxPayloadEditFieldLabel = uilabel(app.EnginePlotterTab);
            app.MaxPayloadEditFieldLabel.Text = 'Max Payload';
            app.MaxPayloadEditField = uieditfield(app.EnginePlotterTab, 'numeric');
            app.MaxPayloadEditField.LowerLimitInclusive = 'off';
            app.MaxPayloadEditField.Limits = [0 Inf];
            app.MaxPayloadEditField.Value = 1000;
            app.PlotFinenessEditFieldLabel = uilabel(app.EnginePlotterTab);
            app.PlotFinenessEditFieldLabel.Text = 'Plot Fineness';
            app.PlotFinenessEditField = uieditfield(app.EnginePlotterTab, 'numeric');
            app.PlotFinenessEditField.Limits = [100 1000];
            app.PlotFinenessEditField.Value = 800;
            app.MaxEnginesEditFieldLabel = uilabel(app.EnginePlotterTab);
            app.MaxEnginesEditFieldLabel.Text = 'Max Engines';
            app.MaxEnginesEditField = uieditfield(app.EnginePlotterTab, 'numeric');
            app.MaxEnginesEditField.Limits = [1 100];
            app.MaxEnginesEditField.RoundFractionalValues = 'on';
            app.MaxEnginesEditField.ValueDisplayFormat = '%.0f';
            app.MaxEnginesEditField.Value = 1;
            app.RequiredTWREditFieldLabel = uilabel(app.EnginePlotterTab);
            app.RequiredTWREditFieldLabel.Text = 'Required TWR';
            app.RequiredTWREditField = uieditfield(app.EnginePlotterTab, 'numeric');
            app.RequiredTWREditField.Limits = [0 Inf];
            app.RequiredTWREditField.Value = 1;
            app.PlotProgress = uieditfield(app.EnginePlotterTab, 'text');
            app.PlotProgress.Editable = 'off';
            app.RunButton = uibutton(app.EnginePlotterTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.AllowableEnginesTab = uitab(app.TabGroup);
            app.AllowableEnginesTab.Title = 'Allowable Engines';
            app.SpiderCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.SpiderCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.SpiderCheckBox.Text = 'Spider';
            app.SpiderCheckBox.Value = true;
            app.TwitchCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.TwitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.TwitchCheckBox.Text = 'Twitch';
            app.TwitchCheckBox.Value = true;
            app.ThudCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.ThudCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.ThudCheckBox.Text = 'Thud';
            app.ThudCheckBox.Value = true;
            app.AntCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.AntCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.AntCheckBox.Text = 'Ant';
            app.AntCheckBox.Value = true;
            app.SparkCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.SparkCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.SparkCheckBox.Text = 'Spark';
            app.SparkCheckBox.Value = true;
            app.TerrierCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.TerrierCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.TerrierCheckBox.Text = 'Terrier';
            app.TerrierCheckBox.Value = true;
            app.ReliantCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.ReliantCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.ReliantCheckBox.Text = 'Reliant';
            app.ReliantCheckBox.Value = true;
            app.SwivelCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.SwivelCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.SwivelCheckBox.Text = 'Swivel';
            app.SwivelCheckBox.Value = true;
            app.VectorCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.VectorCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.VectorCheckBox.Text = 'Vector';
            app.VectorCheckBox.Value = true;
            app.DartCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.DartCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.DartCheckBox.Text = 'Dart';
            app.DartCheckBox.Value = true;
            app.NervCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.NervCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.NervCheckBox.Text = 'Nerv';
            app.NervCheckBox.Value = true;
            app.PoodleCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.PoodleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.PoodleCheckBox.Text = 'Poodle';
            app.PoodleCheckBox.Value = true;
            app.SkipperCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.SkipperCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.SkipperCheckBox.Text = 'Skipper';
            app.SkipperCheckBox.Value = true;
            app.MainsailCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.MainsailCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.MainsailCheckBox.Text = 'Mainsail';
            app.MainsailCheckBox.Value = true;
            app.TwinBoarCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.TwinBoarCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.TwinBoarCheckBox.Text = 'Twin-Boar';
            app.TwinBoarCheckBox.Value = true;
            app.RhinoCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.RhinoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.RhinoCheckBox.Text = 'Rhino';
            app.RhinoCheckBox.Value = true;
            app.MammothCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.MammothCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.MammothCheckBox.Text = 'Mammoth';
            app.MammothCheckBox.Value = true;
            app.RAPIERCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.RAPIERCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.RAPIERCheckBox.Text = 'R.A.P.I.E.R.';
            app.RAPIERCheckBox.Value = true;
            app.DawnCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.DawnCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.DawnCheckBox.Text = 'Dawn';
            app.DawnCheckBox.Value = true;
            app.MastodonCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.MastodonCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.MastodonCheckBox.Text = 'Mastodon';
            app.MastodonCheckBox.Value = true;
            app.CheetahCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.CheetahCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.CheetahCheckBox.Text = 'Cheetah';
            app.CheetahCheckBox.Value = true;
            app.SkiffCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.SkiffCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.SkiffCheckBox.Text = 'Skiff';
            app.SkiffCheckBox.Value = true;
            app.WolfhoundCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.WolfhoundCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.WolfhoundCheckBox.Text = 'Wolfhound';
            app.WolfhoundCheckBox.Value = true;
            app.KodiakCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.KodiakCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.KodiakCheckBox.Text = 'Kodiak';
            app.KodiakCheckBox.Value = true;
            app.CubCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.CubCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.CubCheckBox.Text = 'Cub';
            app.CubCheckBox.Value = true;
            app.FleaCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.FleaCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.FleaCheckBox.Text = 'Flea';
            app.FleaCheckBox.Value = true;
            app.HammerCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.HammerCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.HammerCheckBox.Text = 'Hammer';
            app.HammerCheckBox.Value = true;
            app.ThumperCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.ThumperCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.ThumperCheckBox.Text = 'Thumper';
            app.ThumperCheckBox.Value = true;
            app.KickbackCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.KickbackCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.KickbackCheckBox.Text = 'Kickback';
            app.KickbackCheckBox.Value = true;
            app.SepratronICheckBox = uicheckbox(app.AllowableEnginesTab);
            app.SepratronICheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.SepratronICheckBox.Text = 'Sepratron I';
            app.SepratronICheckBox.Value = true;
            app.ShrimpCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.ShrimpCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.ShrimpCheckBox.Text = 'Shrimp';
            app.ShrimpCheckBox.Value = true;
            app.MiteCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.MiteCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.MiteCheckBox.Text = 'Mite';
            app.MiteCheckBox.Value = true;
            app.ThoroughbredCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.ThoroughbredCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.ThoroughbredCheckBox.Text = 'Thoroughbred';
            app.ThoroughbredCheckBox.Value = true;
            app.ClydesdaleCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.ClydesdaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.ClydesdaleCheckBox.Text = 'Clydesdale';
            app.ClydesdaleCheckBox.Value = true;
            app.PulloxCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.PulloxCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.PulloxCheckBox.Text = 'Pullox';
            app.PulloxCheckBox.Value = true;
            app.BobcatCheckBox = uicheckbox(app.AllowableEnginesTab);
            app.BobcatCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllowEngines, true);
            app.BobcatCheckBox.Text = 'Bobcat';
            app.BobcatCheckBox.Value = true;
            app.DeselectAllButton = uibutton(app.AllowableEnginesTab, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.SelectAllButton = uibutton(app.AllowableEnginesTab, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.RocketPlannerTab = uitab(app.TabGroup);
            app.RocketPlannerTab.Title = 'Rocket Planner';
            app.PayloadtonsEditFieldLabel = uilabel(app.RocketPlannerTab);
            app.PayloadtonsEditFieldLabel.Text = 'Payload (tons)';
            app.PayloadtonsEditField = uieditfield(app.RocketPlannerTab, 'numeric');
            app.PayloadtonsEditField.LowerLimitInclusive = 'off';
            app.PayloadtonsEditField.Limits = [0 Inf];
            app.PayloadtonsEditField.Value = 1;
            app.AddStageButton = uibutton(app.RocketPlannerTab, 'push');
            app.AddStageButton.ButtonPushedFcn = createCallbackFcn(app, @AddStageButtonPushed, true);
            app.AddStageButton.Text = 'Add Stage';
            app.RemoveStageButton = uibutton(app.RocketPlannerTab, 'push');
            app.RemoveStageButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveStageButtonPushed, true);
            app.RemoveStageButton.Text = 'Remove Stage';
            app.StageTypeDropDownLabel = uilabel(app.RocketPlannerTab);
            app.StageTypeDropDownLabel.Text = 'Stage Type';
            app.StageTypeDropDown = uidropdown(app.RocketPlannerTab);
            app.StageTypeDropDown.Items = {'Linear', 'Payload', 'Asparagus'};
            app.StageTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StageTypeDropDownValueChanged, true);
            app.StageTypeDropDown.Value = 'Payload';
            app.MaximumEnginesEditFieldLabel = uilabel(app.RocketPlannerTab);
            app.MaximumEnginesEditFieldLabel.Enable = 'off';
            app.MaximumEnginesEditFieldLabel.Text = 'Maximum Engines';
            app.MaximumEnginesEditField = uieditfield(app.RocketPlannerTab, 'numeric');
            app.MaximumEnginesEditField.Limits = [1 Inf];
            app.MaximumEnginesEditField.RoundFractionalValues = 'on';
            app.MaximumEnginesEditField.Enable = 'off';
            app.MaximumEnginesEditField.Value = 2;
            app.FlightConditionDropDown_2Label = uilabel(app.RocketPlannerTab);
            app.FlightConditionDropDown_2Label.Enable = 'off';
            app.FlightConditionDropDown_2Label.Text = 'Flight Condition';
            app.FlightConditionDropDown_2 = uidropdown(app.RocketPlannerTab);
            app.FlightConditionDropDown_2.Items = {'Sea Level', 'Vacuum'};
            app.FlightConditionDropDown_2.Enable = 'off';
            app.FlightConditionDropDown_2.Value = 'Sea Level';
            app.DeltavEditFieldLabel = uilabel(app.RocketPlannerTab);
            app.DeltavEditFieldLabel.Enable = 'off';
            app.DeltavEditFieldLabel.Text = 'Delta-v';
            app.DeltavEditField = uieditfield(app.RocketPlannerTab, 'numeric');
            app.DeltavEditField.Limits = [1 Inf];
            app.DeltavEditField.Enable = 'off';
            app.DeltavEditField.Value = 1000;
            app.MaximumSymmetryEditFieldLabel = uilabel(app.RocketPlannerTab);
            app.MaximumSymmetryEditFieldLabel.Enable = 'off';
            app.MaximumSymmetryEditFieldLabel.Text = 'Maximum Symmetry';
            app.MaximumSymmetryEditField = uieditfield(app.RocketPlannerTab, 'numeric');
            app.MaximumSymmetryEditField.Limits = [1 12];
            app.MaximumSymmetryEditField.RoundFractionalValues = 'on';
            app.MaximumSymmetryEditField.Enable = 'off';
            app.MaximumSymmetryEditField.Value = 1;
            app.PutengineonAsparaguscoreCheckBox = uicheckbox(app.RocketPlannerTab);
            app.PutengineonAsparaguscoreCheckBox.Enable = 'off';
            app.PutengineonAsparaguscoreCheckBox.Text = 'Put engine on Asparagus core';
            app.PutengineonAsparaguscoreCheckBox.Value = true;
            app.RocketInputTextAreaLabel = uilabel(app.RocketPlannerTab);
            app.RocketInputTextAreaLabel.Text = 'Rocket Input';
            app.RocketInputTextArea = uitextarea(app.RocketPlannerTab);
            app.RocketOutputTextAreaLabel = uilabel(app.RocketPlannerTab);
            app.RocketOutputTextAreaLabel.Text = 'Rocket Output';
            app.RocketOutputTextArea = uitextarea(app.RocketPlannerTab);
            app.RocketOutputTextArea.Editable = 'off';
            app.RunOptimizerButton = uibutton(app.RocketPlannerTab, 'push');
            app.RunOptimizerButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizerButtonValueChanged, true);
            app.RunOptimizerButton.Text = 'Run Optimizer';
            app.UITable = uitable(app.RocketPlannerTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.WhichOptimalDropDownLabel = uilabel(app.RocketPlannerTab);
            app.WhichOptimalDropDownLabel.Text = 'Which Optimal';
            app.WhichOptimalDropDown = uidropdown(app.RocketPlannerTab);
            app.WhichOptimalDropDown.Items = {'Lowest Cost', 'Lowest Mass'};
            app.WhichOptimalDropDown.ValueChangedFcn = createCallbackFcn(app, @WhichOptimalDropDownValueChanged, true);
            app.WhichOptimalDropDown.Value = 'Lowest Cost';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GillermooKerbalEngineMapper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end